classdef Robotel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PanelCon                  matlab.ui.container.Panel
        BDiscon                   matlab.ui.control.Button
        BCon                      matlab.ui.control.Button
        LabelDropDown             matlab.ui.control.Label
        PortList                  matlab.ui.control.DropDown
        BFix                      matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        UIAxesM0                  matlab.ui.control.UIAxes
        UIAxesM1                  matlab.ui.control.UIAxes
        SerialDataTextArea        matlab.ui.control.TextArea
        Motor0Panel               matlab.ui.container.Panel
        KiEditFieldLabel          matlab.ui.control.Label
        M0KiEditField             matlab.ui.control.NumericEditField
        KdEditFieldLabel          matlab.ui.control.Label
        M0KdEditField             matlab.ui.control.NumericEditField
        KpEditFieldLabel          matlab.ui.control.Label
        M0KpEditField             matlab.ui.control.NumericEditField
        M0SetPidButton            matlab.ui.control.Button
        SetpointEditField_3Label  matlab.ui.control.Label
        M0SetpointEditField       matlab.ui.control.NumericEditField
        M0SetSetpointButton       matlab.ui.control.Button
        M0GetPidButton            matlab.ui.control.Button
        Motor1Panel               matlab.ui.container.Panel
        KiEditField_2Label        matlab.ui.control.Label
        M1KiEditField             matlab.ui.control.NumericEditField
        KdEditField_2Label        matlab.ui.control.Label
        M1KdEditField             matlab.ui.control.NumericEditField
        KpEditField_2Label        matlab.ui.control.Label
        M1KpEditField             matlab.ui.control.NumericEditField
        M1SetPidButton            matlab.ui.control.Button
        SetpointEditField_2Label  matlab.ui.control.Label
        M1SetpointEditField       matlab.ui.control.NumericEditField
        M1SetSetpointButton       matlab.ui.control.Button
        M1GetPidButton            matlab.ui.control.Button
        UIAxesGi                  matlab.ui.control.UIAxes
        GiroPanel                 matlab.ui.container.Panel
        KiEditField_3Label        matlab.ui.control.Label
        GiKiEditField             matlab.ui.control.NumericEditField
        KdEditField_3Label        matlab.ui.control.Label
        GiKdEditField             matlab.ui.control.NumericEditField
        KpEditField_3Label        matlab.ui.control.Label
        GiKpEditField             matlab.ui.control.NumericEditField
        GiSetPidButton            matlab.ui.control.Button
        SetpointEditFieldLabel    matlab.ui.control.Label
        GiSetpointEditField       matlab.ui.control.NumericEditField
        GiSetSetpointButton       matlab.ui.control.Button
        G1GetPidButton            matlab.ui.control.Button
        M0StatusLabel             matlab.ui.control.Label
        M1StatusLabel             matlab.ui.control.Label
        GiStatusLabel             matlab.ui.control.Label
        AngleLabel                matlab.ui.control.Label
        GiroGauge                 matlab.ui.control.Gauge
        UIAxesGiPid               matlab.ui.control.UIAxes
        GiroToMotSwitchLabel      matlab.ui.control.Label
        GiroToMotSwitch           matlab.ui.control.Switch
        RawGiroPrintsSwitchLabel  matlab.ui.control.Label
        RawGiroPrintsSwitch       matlab.ui.control.Switch
        MotPrintsSwitchLabel      matlab.ui.control.Label
        MotPrintsSwitch           matlab.ui.control.Switch
        LoopTimeLabel             matlab.ui.control.Label
        GiroPrintsSwitchLabel     matlab.ui.control.Label
        GiroPrintsSwitch          matlab.ui.control.Switch
        PanelCon_2                matlab.ui.container.Panel
        BDiscon_2                 matlab.ui.control.Button
        BCon_2                    matlab.ui.control.Button
        LabelDropDown_2           matlab.ui.control.Label
        PortList_2                matlab.ui.control.DropDown
        BFix_2                    matlab.ui.control.Button
        Lamp_2                    matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        
        GREEN_COLOR = [0 1 0];
        RED_COLOR = [1 0 0];
        GRAY_COLOR = [0.4706 0.5059 0.4706];
        
        
        SerialDuino % SerialPort
        SerialDuno_baud=115200;
        SerialRemote % SerialPort
        SerialRemote_baud=115200;
        
        Serial_text="salut";
        UpdateTimer % timer (for update GUI and send data)
        
        %%%
        
        
        Data_Samples = 80;
        Data_Scale;
        
        Mot0_Enc
        Mot0_PidO
        Mot0_Dir
        Mot0_ph;
        Mot0_Setpoint;
        
        Mot1_Enc
        Mot1_PidO
        Mot1_Dir
        Mot1_ph;
        Mot1_Setpoint;
        
        Giro_RawAccY;
        Giro_RawAccZ;
        Giro_RawRotX;
        
        Giro_Output;
        Giro_Input;
        
        Giro_phpid;
        Giro_ph;
        
        time_step=0.2;
        
        %%%
    end
    
    methods (Access = private)
        function ReadDataRemote(app)
            
            % read and scale data from serial port
            newText=fscanf(app.SerialRemote);
            fprintf(app.SerialDuino,newText);
        end
        
        function ReadDataDuino(app)
            % read and scale data from serial port
            newText=fscanf(app.SerialDuino);
            app.Serial_text=[newText; app.Serial_text];
            
            sz = (size(app.Serial_text ));
            if(sz(1)>20)
                app.Serial_text = app.Serial_text(1:20);
            end
            
            content=textscan(newText,'%s');
            
            
            if(strcmp(content{1}{1},'ENC:')) %encoders
                app.Mot0_Enc=[app.Mot0_Enc(2:end) str2num(content{1}{2})];
                app.Mot1_Enc=[app.Mot1_Enc(2:end) str2num(content{1}{4})];
                
                app.Mot0_Dir=str2num(content{1}{3});
                app.Mot1_Dir=str2num(content{1}{5});


                %                 set(plotEncs(1),'XData',encx,'YData',enc0);
                %                 set(plotEncs(2),'XData',encx,'YData',enc1);
                %                 if(dir0==1)
                %                     aEnc0.String='Enc0 FWD';
                %                     aEnc0.Color='g';
                %                 else
                %                     aEnc0.String='Enc0 BKD';
                %                     aEnc0.Color='r';
                %                 end
                %
                %                 if(dir1==1)
                %                     aEnc1.String='Enc1 FWD';
                %                     aEnc1.Color='g';
                %                 else
                %                     aEnc1.String='Enc1 BKD';
                %                     aEnc1.Color='r';
                %                 end
                
            elseif (strcmp(content{1}{1},'DD:'))
                if (strcmp(content{1}{2},"M0"))
                    app.M0KpEditField.Value=str2double(content{1}{3});
                    app.M0KiEditField.Value=str2double(content{1}{4});
                    app.M0KdEditField.Value=str2double(content{1}{5});
                    app.M0SetpointEditField.Value=str2num(content{1}{6});
                elseif (strcmp(content{1}{2},"M1"))
                    app.M1KpEditField.Value=str2double(content{1}{3});
                    app.M1KiEditField.Value=str2double(content{1}{4});
                    app.M1KdEditField.Value=str2double(content{1}{5});
                    app.M1SetpointEditField.Value=str2num(content{1}{6});
                elseif (strcmp(content{1}{2},"Gi"))
                    app.GiKpEditField.Value=str2double(content{1}{3});
                    app.GiKiEditField.Value=str2double(content{1}{4});
                    app.GiKdEditField.Value=str2double(content{1}{5});
                    app.GiSetpointEditField.Value=str2num(content{1}{6});
                end
                
            elseif (strcmp(content{1}{1},'PID:'))
                app.Mot0_PidO=[app.Mot0_PidO(2:end) str2double(content{1}{2})];
                app.Mot1_PidO=[app.Mot1_PidO(2:end) str2double(content{1}{3})];
                
            elseif (strcmp(content{1}{1},'SET:'))
                app.Mot0_Setpoint=[app.Mot0_Setpoint(2:end) str2double(content{1}{2})];
                app.Mot1_Setpoint=[app.Mot1_Setpoint(2:end) str2double(content{1}{3})];
                
            elseif (strcmp(content{1}{1},'GRD:'))
                app.Giro_RawAccY=[app.Giro_RawAccY(2:end) str2double(content{1}{2})];
                app.Giro_RawAccZ=[app.Giro_RawAccZ(2:end) str2double(content{1}{3})];
                app.Giro_RawRotX=[app.Giro_RawRotX(2:end) str2double(content{1}{4})];
            elseif (strcmp(content{1}{1},'GIO:'))
                app.GiroGauge.Value=str2num(content{1}{2});
                app.Giro_Input=[app.Giro_Input(2:end) str2double(content{1}{2})];
                app.Giro_Output=[app.Giro_Output(2:end) str2double(content{1}{3})];
            elseif (strcmp(content{1}{1},'MC:'))
                app.LoopTimeLabel.Text=sprintf("Loop time: %f" ,str2num(content{1}{2})/1000);
            end
        end
        
        function UpdateGui(app)
            set(app.Mot0_ph(1),'YData',app.Mot0_Enc);
            set(app.Mot0_ph(2),'YData',app.Mot0_PidO/6);
            set(app.Mot0_ph(3),'YData',app.Mot0_Setpoint);
            
            axis(app.UIAxesM0,[0 inf app.Mot0_Setpoint(end)-60 app.Mot0_Setpoint(end)+60]);
            
            set(app.Mot1_ph(1),'YData',app.Mot1_Enc);
            set(app.Mot1_ph(2),'YData',app.Mot1_PidO/6);
            set(app.Mot1_ph(3),'YData',app.Mot1_Setpoint);
            axis(app.UIAxesM1,[0 inf app.Mot1_Setpoint(end)-60 app.Mot1_Setpoint(end)+60]);
            
            set(app.Giro_ph(1),'YData',app.Giro_RawAccY);
            set(app.Giro_ph(2),'YData',app.Giro_RawAccZ);
            set(app.Giro_ph(3),'YData',app.Giro_RawRotX);
            
            set(app.Giro_phpid(1),'YData',app.Giro_Input);
            set(app.Giro_phpid(2),'YData',app.Giro_Output);
            
            app.M0StatusLabel.Text=sprintf("STP: %g ENC: %g PID: %g",app.Mot0_Setpoint(end), app.Mot0_Enc(end), app.Mot0_PidO(end));
            app.M1StatusLabel.Text=sprintf("STP: %g ENC: %g PID: %g",app.Mot1_Setpoint(end), app.Mot1_Enc(end), app.Mot1_PidO(end));
            
            app.GiStatusLabel.Text=sprintf("Angle: %g PID: %g",app.Giro_Input(end), app.Giro_Output(end));
            
            app.SerialDataTextArea.Value=app.Serial_text;
            
            
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end


            app.Data_Scale=(1 : 1 : app.Data_Samples);
            
            app.Mot0_Enc=zeros(1,app.Data_Samples);
            app.Mot1_Enc=zeros(1,app.Data_Samples);
            
            app.Mot0_PidO=zeros(1,app.Data_Samples);
            app.Mot1_PidO=zeros(1,app.Data_Samples);
            
            app.Giro_RawAccY=zeros(1,app.Data_Samples);
            app.Giro_RawAccZ=zeros(1,app.Data_Samples);
            app.Giro_RawRotX=zeros(1,app.Data_Samples);
            
            app.Giro_Input=zeros(1,app.Data_Samples);
            app.Giro_Output=zeros(1,app.Data_Samples);
            
            
            % set up timer
            app.UpdateTimer = timer;
            app.UpdateTimer.TimerFcn = @(~, ~)app.UpdateGui;
            app.UpdateTimer.ExecutionMode = 'fixedRate';
            app.UpdateTimer.Period = app.time_step;
            app.UpdateTimer.BusyMode = 'drop';
            
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
            app.PortList_2.Items=p.AvailableSerialPorts;
            
            app.Serial_text=app.SerialDataTextArea.Value;
            app.Mot0_Setpoint=ones(1,app.Data_Samples)*app.M0SetpointEditField.Value;
            app.Mot1_Setpoint=ones(1,app.Data_Samples)*app.M1SetpointEditField.Value;
            
            app.Mot0_ph=plot(app.UIAxesM0,app.Data_Scale,app.Mot0_Enc,app.Data_Scale,app.Mot0_PidO,app.Data_Scale,app.Mot0_Setpoint,'--');
            legend(app.UIAxesM0,'Enc','Pid','Setp','Location','northwest','Orientation','horizontal');
            axis(app.UIAxesM0,[0 inf -65 65]);
            
            app.Mot1_ph=plot(app.UIAxesM1,app.Data_Scale,app.Mot1_Enc,app.Data_Scale,app.Mot1_PidO,app.Data_Scale,app.Mot1_Setpoint,'--');
            legend(app.UIAxesM1,'Enc','Pid','Setp','Location','northwest','Orientation','horizontal');
            axis(app.UIAxesM1,[0 inf -65 65]);
            
            app.Giro_ph=plot(app.UIAxesGi,app.Data_Scale,app.Giro_RawAccY,app.Data_Scale,app.Giro_RawAccZ,app.Data_Scale,app.Giro_RawRotX);
            legend(app.UIAxesGi,'RawAccY','RawAccZ','RawRotX','Location','northwest','Orientation','horizontal');
            
            app.Giro_phpid=plot(app.UIAxesGiPid,app.Data_Scale,app.Giro_Input,app.Data_Scale,app.Giro_Output);
            legend(app.UIAxesGiPid,'Input angle','Output pid','Location','northwest','Orientation','horizontal');
            
            
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if strcmp(app.UpdateTimer.running, 'off')
                app.SerialDuino = serial(app.PortList.Value,'BaudRate',app.SerialDuno_baud);
                % call ReadData function if buffer contains uint16 (2 bytes)
                app.SerialDuino.BytesAvailableFcnMode = 'terminator';
                app.SerialDuino.BytesAvailableFcn = @(~, ~)app.ReadDataDuino;
                fopen(app.SerialDuino);
                start(app.UpdateTimer);
                app.Lamp.Color = app.GREEN_COLOR;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if strcmp(app.UpdateTimer.running, 'on')
                stop(app.UpdateTimer);
                stopasync(app.SerialDuino)
                fclose(app.SerialDuino);
                app.Lamp.Color = app.RED_COLOR;
                
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
            end
            
        end

        % Button pushed function: M0SetPidButton
        function M0SetPidButtonPushed(app, event)
            
            OutputString=sprintf("SP M0 %f %f %f",app.M0KpEditField.Value,app.M0KiEditField.Value,app.M0KdEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Button pushed function: M1SetPidButton
        function M1SetPidButtonPushed(app, event)
            OutputString=sprintf("SP M1 %f %f %f",app.M1KpEditField.Value,app.M1KiEditField.Value,app.M1KdEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Button pushed function: GiSetPidButton
        function GiSetPidButtonPushed(app, event)
            OutputString=sprintf("SP Gi %f %f %f",app.GiKpEditField.Value,app.GiKiEditField.Value,app.GiKdEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Button pushed function: M0GetPidButton
        function M0GetPidButtonPushed(app, event)
            fprintf(app.SerialDuino,"GD M0");
        end

        % Button pushed function: M1GetPidButton
        function M1GetPidButtonPushed(app, event)
            fprintf(app.SerialDuino,"GD M1");
        end

        % Button pushed function: G1GetPidButton
        function G1GetPidButtonPushed(app, event)
            fprintf(app.SerialDuino,"GD Gi");
        end

        % Button pushed function: M0SetSetpointButton
        function M0SetSetpointButtonPushed(app, event)
            OutputString=sprintf("SS M0 %f",app.M0SetpointEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Button pushed function: M1SetSetpointButton
        function M1SetSetpointButtonPushed(app, event)
            OutputString=sprintf("SS M1 %f",app.M1SetpointEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Button pushed function: GiSetSetpointButton
        function GiSetSetpointButtonPushed(app, event)
            OutputString=sprintf("SS Gi %f",app.GiSetpointEditField.Value);
            fprintf(app.SerialDuino,OutputString);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            delete(app)
            
        end

        % Callback function: BFix, BFix_2
        function BFixButtonPushed(app, event)
            % delete all serial port objets from MATLAB memory
            % (Instrument Control Toolbox required)
            delete(instrfind);
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            p = instrhwinfo('serial');
            app.PortList.Items = p.AvailableSerialPorts;
        end

        % Value changed function: GiroToMotSwitch
        function GiroToMotSwitchValueChanged(app, event)
            value = app.GiroToMotSwitch.Value;
            if (strcmp(value,'On'))
                fprintf(app.SerialDuino,"GC_ON");
            else
                fprintf(app.SerialDuino,"GC_OFF");
            end
        end

        % Value changed function: RawGiroPrintsSwitch
        function RawGiroPrintsSwitchValueChanged(app, event)
            value = app.RawGiroPrintsSwitch.Value;
            if (strcmp(value,'On'))
                fprintf(app.SerialDuino,"GRP_ON");
            else
                fprintf(app.SerialDuino,"GRP_OFF");
            end
        end

        % Value changed function: MotPrintsSwitch
        function MotPrintsSwitchValueChanged(app, event)
            value = app.MotPrintsSwitch.Value;
            if (strcmp(value,'On'))
                fprintf(app.SerialDuino,"MP_ON");
            else
                fprintf(app.SerialDuino,"MP_OFF");
            end
        end

        % Value changed function: GiroPrintsSwitch
        function GiroPrintsSwitchValueChanged(app, event)
            value = app.GiroPrintsSwitch.Value;
            if (strcmp(value,'On'))
                fprintf(app.SerialDuino,"GP_ON");
            else
                fprintf(app.SerialDuino,"GP_OFF");
            end
        end

        % Callback function
        function UpButtonPushed(app, event)
            fprintf(app.SerialDuino,"XU");
        end

        % Callback function
        function DownButtonPushed(app, event)
            fprintf(app.SerialDuino,"XD");
        end

        % Callback function
        function LeftButtonPushed(app, event)
            fprintf(app.SerialDuino,"XL");
        end

        % Callback function
        function RightButtonPushed(app, event)
            fprintf(app.SerialDuino,"XR");
        end

        % Button pushed function: BCon_2
        function BCon_2ButtonPushed(app, event)
            
            app.SerialRemote= serial(app.PortList_2.Value,'BaudRate',app.SerialRemote_baud);
            %  app.SerialRemote.Terminator='\n';
            % call ReadData function if buffer contains uint16 (2 bytes)
            app.SerialRemote.BytesAvailableFcnMode = 'terminator';
            app.SerialRemote.BytesAvailableFcn = @(~, ~)app.ReadDataRemote;
            fopen(app.SerialRemote);
            app.Lamp_2.Color = app.GREEN_COLOR;
            
        end

        % Button pushed function: BDiscon_2
        function BDiscon_2ButtonPushed(app, event)
            
            
            stopasync(app.SerialRemote)
            fclose(app.SerialRemote);
            app.Lamp_2.Color = app.RED_COLOR;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PanelCon = uipanel(app.UIFigure);
            app.PanelCon.Title = '      Connection Robot';
            app.BDiscon = uibutton(app.PanelCon, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.BCon = uibutton(app.PanelCon, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.LabelDropDown = uilabel(app.PanelCon);
            app.LabelDropDown.Text = 'Port';
            app.PortList = uidropdown(app.PanelCon);
            app.BFix = uibutton(app.PanelCon, 'push');
            app.BFix.ButtonPushedFcn = createCallbackFcn(app, @BFixButtonPushed, true);
            app.BFix.Text = 'Fix and refresh';
            app.Lamp = uilamp(app.PanelCon);
            app.UIAxesM0 = uiaxes(app.UIFigure);
            title(app.UIAxesM0, 'Motor 0')
            app.UIAxesM1 = uiaxes(app.UIFigure);
            title(app.UIAxesM1, 'Motor 1')
            app.SerialDataTextArea = uitextarea(app.UIFigure);
            app.SerialDataTextArea.Editable = 'off';
            app.Motor0Panel = uipanel(app.UIFigure);
            app.Motor0Panel.Title = 'Motor 0';
            app.KiEditFieldLabel = uilabel(app.Motor0Panel);
            app.KiEditFieldLabel.Text = 'Ki';
            app.M0KiEditField = uieditfield(app.Motor0Panel, 'numeric');
            app.KdEditFieldLabel = uilabel(app.Motor0Panel);
            app.KdEditFieldLabel.Text = 'Kd';
            app.M0KdEditField = uieditfield(app.Motor0Panel, 'numeric');
            app.KpEditFieldLabel = uilabel(app.Motor0Panel);
            app.KpEditFieldLabel.Text = 'Kp';
            app.M0KpEditField = uieditfield(app.Motor0Panel, 'numeric');
            app.M0SetPidButton = uibutton(app.Motor0Panel, 'push');
            app.M0SetPidButton.ButtonPushedFcn = createCallbackFcn(app, @M0SetPidButtonPushed, true);
            app.M0SetPidButton.Text = 'SetPid';
            app.SetpointEditField_3Label = uilabel(app.Motor0Panel);
            app.SetpointEditField_3Label.Text = 'Setpoint';
            app.M0SetpointEditField = uieditfield(app.Motor0Panel, 'numeric');
            app.M0SetSetpointButton = uibutton(app.Motor0Panel, 'push');
            app.M0SetSetpointButton.ButtonPushedFcn = createCallbackFcn(app, @M0SetSetpointButtonPushed, true);
            app.M0SetSetpointButton.Text = 'SetSetpoint';
            app.M0GetPidButton = uibutton(app.Motor0Panel, 'push');
            app.M0GetPidButton.ButtonPushedFcn = createCallbackFcn(app, @M0GetPidButtonPushed, true);
            app.M0GetPidButton.Text = 'GetData';
            app.Motor1Panel = uipanel(app.UIFigure);
            app.Motor1Panel.Title = 'Motor 1';
            app.KiEditField_2Label = uilabel(app.Motor1Panel);
            app.KiEditField_2Label.Text = 'Ki';
            app.M1KiEditField = uieditfield(app.Motor1Panel, 'numeric');
            app.KdEditField_2Label = uilabel(app.Motor1Panel);
            app.KdEditField_2Label.Text = 'Kd';
            app.M1KdEditField = uieditfield(app.Motor1Panel, 'numeric');
            app.KpEditField_2Label = uilabel(app.Motor1Panel);
            app.KpEditField_2Label.Text = 'Kp';
            app.M1KpEditField = uieditfield(app.Motor1Panel, 'numeric');
            app.M1SetPidButton = uibutton(app.Motor1Panel, 'push');
            app.M1SetPidButton.ButtonPushedFcn = createCallbackFcn(app, @M1SetPidButtonPushed, true);
            app.M1SetPidButton.Text = 'SetPid';
            app.SetpointEditField_2Label = uilabel(app.Motor1Panel);
            app.SetpointEditField_2Label.Text = 'Setpoint';
            app.M1SetpointEditField = uieditfield(app.Motor1Panel, 'numeric');
            app.M1SetSetpointButton = uibutton(app.Motor1Panel, 'push');
            app.M1SetSetpointButton.ButtonPushedFcn = createCallbackFcn(app, @M1SetSetpointButtonPushed, true);
            app.M1SetSetpointButton.Text = 'SetSetpoint';
            app.M1GetPidButton = uibutton(app.Motor1Panel, 'push');
            app.M1GetPidButton.ButtonPushedFcn = createCallbackFcn(app, @M1GetPidButtonPushed, true);
            app.M1GetPidButton.Text = 'GetData';
            app.UIAxesGi = uiaxes(app.UIFigure);
            title(app.UIAxesGi, 'Giro Raw')
            app.GiroPanel = uipanel(app.UIFigure);
            app.GiroPanel.Title = 'Giro';
            app.KiEditField_3Label = uilabel(app.GiroPanel);
            app.KiEditField_3Label.Text = 'Ki';
            app.GiKiEditField = uieditfield(app.GiroPanel, 'numeric');
            app.KdEditField_3Label = uilabel(app.GiroPanel);
            app.KdEditField_3Label.Text = 'Kd';
            app.GiKdEditField = uieditfield(app.GiroPanel, 'numeric');
            app.KpEditField_3Label = uilabel(app.GiroPanel);
            app.KpEditField_3Label.Text = 'Kp';
            app.GiKpEditField = uieditfield(app.GiroPanel, 'numeric');
            app.GiSetPidButton = uibutton(app.GiroPanel, 'push');
            app.GiSetPidButton.ButtonPushedFcn = createCallbackFcn(app, @GiSetPidButtonPushed, true);
            app.GiSetPidButton.Text = 'SetPid';
            app.SetpointEditFieldLabel = uilabel(app.GiroPanel);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.GiSetpointEditField = uieditfield(app.GiroPanel, 'numeric');
            app.GiSetSetpointButton = uibutton(app.GiroPanel, 'push');
            app.GiSetSetpointButton.ButtonPushedFcn = createCallbackFcn(app, @GiSetSetpointButtonPushed, true);
            app.GiSetSetpointButton.Text = 'SetSetpoint';
            app.G1GetPidButton = uibutton(app.GiroPanel, 'push');
            app.G1GetPidButton.ButtonPushedFcn = createCallbackFcn(app, @G1GetPidButtonPushed, true);
            app.G1GetPidButton.Text = 'GetData';
            app.M0StatusLabel = uilabel(app.UIFigure);
            app.M0StatusLabel.Text = 'M0Status';
            app.M1StatusLabel = uilabel(app.UIFigure);
            app.M1StatusLabel.Text = 'M1Status';
            app.GiStatusLabel = uilabel(app.UIFigure);
            app.GiStatusLabel.Text = 'GiStatus';
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle';
            app.GiroGauge = uigauge(app.UIFigure, 'circular');
            app.GiroGauge.Limits = [-150 150];
            app.GiroGauge.MajorTicks = [-150 -120 -90 -60 -30 0 30 60 90 120 150];
            app.UIAxesGiPid = uiaxes(app.UIFigure);
            title(app.UIAxesGiPid, 'Giro Pid')
            app.GiroToMotSwitchLabel = uilabel(app.UIFigure);
            app.GiroToMotSwitchLabel.Text = 'Giro To Mot';
            app.GiroToMotSwitch = uiswitch(app.UIFigure, 'slider');
            app.GiroToMotSwitch.ValueChangedFcn = createCallbackFcn(app, @GiroToMotSwitchValueChanged, true);
            app.GiroToMotSwitch.Value = 'On';
            app.RawGiroPrintsSwitchLabel = uilabel(app.UIFigure);
            app.RawGiroPrintsSwitchLabel.Text = 'Raw Giro Prints';
            app.RawGiroPrintsSwitch = uiswitch(app.UIFigure, 'slider');
            app.RawGiroPrintsSwitch.ValueChangedFcn = createCallbackFcn(app, @RawGiroPrintsSwitchValueChanged, true);
            app.MotPrintsSwitchLabel = uilabel(app.UIFigure);
            app.MotPrintsSwitchLabel.Text = 'Mot Prints';
            app.MotPrintsSwitch = uiswitch(app.UIFigure, 'slider');
            app.MotPrintsSwitch.ValueChangedFcn = createCallbackFcn(app, @MotPrintsSwitchValueChanged, true);
            app.LoopTimeLabel = uilabel(app.UIFigure);
            app.LoopTimeLabel.Text = 'LoopTime:';
            app.GiroPrintsSwitchLabel = uilabel(app.UIFigure);
            app.GiroPrintsSwitchLabel.Text = 'Giro Prints';
            app.GiroPrintsSwitch = uiswitch(app.UIFigure, 'slider');
            app.GiroPrintsSwitch.ValueChangedFcn = createCallbackFcn(app, @GiroPrintsSwitchValueChanged, true);
            app.PanelCon_2 = uipanel(app.UIFigure);
            app.PanelCon_2.Title = '      Connection Remote';
            app.BDiscon_2 = uibutton(app.PanelCon_2, 'push');
            app.BDiscon_2.ButtonPushedFcn = createCallbackFcn(app, @BDiscon_2ButtonPushed, true);
            app.BDiscon_2.Text = 'Disconnect';
            app.BCon_2 = uibutton(app.PanelCon_2, 'push');
            app.BCon_2.ButtonPushedFcn = createCallbackFcn(app, @BCon_2ButtonPushed, true);
            app.BCon_2.Text = 'Connect';
            app.LabelDropDown_2 = uilabel(app.PanelCon_2);
            app.LabelDropDown_2.Text = 'Port';
            app.PortList_2 = uidropdown(app.PanelCon_2);
            app.BFix_2 = uibutton(app.PanelCon_2, 'push');
            app.BFix_2.ButtonPushedFcn = createCallbackFcn(app, @BFixButtonPushed, true);
            app.BFix_2.Text = 'Fix and refresh';
            app.Lamp_2 = uilamp(app.PanelCon_2);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Robotel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end